classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        rectangle6                      matlab.ui.control.Label
        rectangle5                      matlab.ui.control.Label
        rectangle4                      matlab.ui.control.Label
        rectangle3                      matlab.ui.control.Label
        rectangle2                      matlab.ui.control.Label
        rectangle1                      matlab.ui.control.Label
        Button_SLM_connect              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Lamp_SLM_connected              matlab.ui.control.Lamp
        LensphaseSpinnerLabel           matlab.ui.control.Label
        Spinner_Lens_phase              matlab.ui.control.Spinner
        Button_send_lens_phase          matlab.ui.control.Button
        Lamp_sent_lens_phase            matlab.ui.control.Lamp
        Table_calibration_parameters    matlab.ui.control.Table
        Button_Generate_grid            matlab.ui.control.Button
        Check_include_lens_phase_Grid   matlab.ui.control.CheckBox
        Button_send_pattern_phase       matlab.ui.control.Button
        Button_calculate_pattern_phase  matlab.ui.control.Button
        Text_GenerateIntensitypatternLabel  matlab.ui.control.Label
        Button_Upload_image             matlab.ui.control.Button
        Text_Calculate_the_phase        matlab.ui.control.Label
        Text_Send_the_phase_to_SLM      matlab.ui.control.Label
        Button_Store_manual_pattern     matlab.ui.control.Button
        StoredIntensityPatternsLabel    matlab.ui.control.Label
        DropDown_Stored_patterns        matlab.ui.control.DropDown
        List_messages_software_speaking  matlab.ui.control.ListBox
        Text_Points_grid_intensity_pattern  matlab.ui.control.Label
        Text_Custom_intensity_pattern   matlab.ui.control.Label
        Text_title_FOV                  matlab.ui.control.Label
        Text_Calibration_parameters     matlab.ui.control.Label
        Text_Log_window                 matlab.ui.control.Label
        Switch_FOV_panel                matlab.ui.control.Switch
        Switch_SLM_panel                matlab.ui.control.Switch
        SLMattachedLabel                matlab.ui.control.Label
        Switch_SLM_attached             matlab.ui.control.Switch
        Axes_Phase                      matlab.ui.control.UIAxes
        Axes_Target_intensity_pattern   matlab.ui.control.UIAxes
        Axes_Uploaded_image             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        verbose = 0;                        % Description
        SLM_attached = 0;                   % Description
        SLM                                 % Description
        lambda_um = 0.92;
        focalDist_um = 12.5e3;
        refractiveIndex = 1.33;
        objNA = 0.8;
        illuminationWavelength = 920e-9;
        SLM_size_px
        original_SLM_size_px
        blackBand
        lens_phase_spinner_value = 6.3
        flatPhase_toBeSent
        nPoints = 3;
        step = 150;
        zoomXY = 0.51;
        rotation = 107;
        mirroring = 0;
        nPlanes = 1;
        zDistance = 0;
        ITarget
        FOV
        mask
        
        SLMControlGUI_parameters = struct(...
            ...% Meadowlark 512x512 is controlled through PCIe 16-bit
            ...'SLM_control_type','PCIe_16bit',...
            ...'SLM_LUTpath','C:\Program Files\Meadowlark Optics\Blink OverDrive Plus\LUT Files',... % should be a valid path, included in MATLAB path
            ...'SLM_globalLUTfilename','slm4073_at1064_P8.lut',...% should be a valid filename
            ...'SLM_SDKpath','C:\Program Files\Meadowlark Optics\Blink OverDrive Plus\SDK',...% should be a valid path, included in MATLAB path
            ...'SLM_size',[512 512],...
            ...'SLM_layout','square',...
            ...% Overdrive parameters (only for Meadowlark)
            ...'SLM_overdriveActive',0,...
            ...'SLM_regionalLUTfilename','slm4073_regional.txt',...% should be a valid filename
            ...'SLM_maxTransients',20,...% max 20
            ...
            ...% Hamamatsu X10468-07 is controlled through DVI
            'SLM_control_type','DVI',...
            'SLM_LUTpath','C:\Users\Public\Valentina\SLMControlGUI_v2.9_room1_20190131\SLMControlGUI\Hamamatsu',... % should be a valid path, included in MATLAB path
            'SLM_globalLUTfilename','lut.txt',...% should be a valid filename
            'SLM_size_px', [800,600]);            %[horizontal,vertical]
        
        IFT_field                    % Description
        FT_field
        roiwindow % Description
        phase % Description
        Stored_Patterns
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Check size of SLM. If rectangular, generate the blackbands to
            % be used at the borders of square calculations 
            app.original_SLM_size_px = app.SLMControlGUI_parameters.SLM_size_px;
            if size(app.original_SLM_size_px,1)~=size(app.original_SLM_size_px,2)
                app.blackBand = zeros(min(app.original_SLM_size_px), (max(app.original_SLM_size_px)-min(app.original_SLM_size_px))/2);
                app.SLM_size_px = [min(app.original_SLM_size_px) min(app.original_SLM_size_px)];
            end
            app.Axes_Phase.DataAspectRatio = [app.original_SLM_size_px(2) app.original_SLM_size_px(1) 1];
            
            % Initialization of the calibration table
            app.Table_calibration_parameters.Data = [app.nPoints, app.step, app.zoomXY, app.rotation, app.mirroring, app.nPlanes, app.zDistance];
            app.Table_calibration_parameters.ColumnFormat = {'short','short','bank','short','short'};
            s1 = uistyle('FontColor','k','FontWeight','bold');
            addStyle(app.Table_calibration_parameters,s1,'column',[1 2]);
            
            
            % Initialization of dropdown and listbox
            app.DropDown_Stored_patterns.Items = {};
            app.DropDown_Stored_patterns.ItemsData = {};
            app.List_messages_software_speaking.Items = {};
            
            % Initialization of flat phase
            flatPhase = lensPhaseModulation(app.SLM_size_px, app.lens_phase_spinner_value, app.lambda_um, app.focalDist_um);
            flatPhase = [app.blackBand, flatPhase, app.blackBand];
            app.flatPhase_toBeSent = mod (flatPhase, 2*pi)./(2*pi);
        end

        % Button pushed function: Button_SLM_connect
        function Button_SLM_connectPushed(app, event)
            
            if app.SLM_attached
                app.SLM = SLM_Hamamatsu(app.SLMControlGUI_parameters);
                app.Lamp_SLM_connected.Color = 'green';
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   SLM connected\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
            else
                app.Lamp_SLM_connected.Color = 'red';
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   !!! SLM not connected\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
            end
            
        end

        % Value changing function: Spinner_Lens_phase
        function Spinner_Lens_phaseValueChanging(app, event)
            
            app.lens_phase_spinner_value = event.Value;
            flatPhase = lensPhaseModulation(app.SLM_size_px, app.lens_phase_spinner_value, app.lambda_um, app.focalDist_um);
            flatPhase = [app.blackBand, flatPhase, app.blackBand];
            
            app.flatPhase_toBeSent = mod (flatPhase, 2*pi)./(2*pi);
            app.Lamp_sent_lens_phase.Color = 'black';
            
            % Show the image in the phase window
            pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
            imshow(app.flatPhase_toBeSent, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Phase.DataAspectRatioMode = 'manual';
            app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
        end

        % Button pushed function: Button_send_lens_phase
        function Button_send_lens_phasePushed(app, event)
            
            if app.SLM_attached
                app.SLM.sendMap(app.flatPhase_toBeSent);
                app.Lamp_sent_lens_phase.Color = 'green';
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Lens phase sent to SLM\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
            else
                app.Lamp_sent_lens_phase.Color = 'red';
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   !!! Lens phase not sent to SLM\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
            end
            
        end

        % Button pushed function: Button_Generate_grid
        function Button_Generate_gridPushed(app, event)
            
            nPoints = app.Table_calibration_parameters.Data(1);
            step = app.Table_calibration_parameters.Data(2);
            zoomXY = app.Table_calibration_parameters.Data(3);
            rotation = app.Table_calibration_parameters.Data(4);
            mirroring = app.Table_calibration_parameters.Data(5);
            nPlanes = app.Table_calibration_parameters.Data(6);
            zDistance = app.Table_calibration_parameters.Data(7);
            
%             I = zeros(app.SLM_size_px);
            I = zeros([app.SLM_size_px nPlanes]);
            xp = [0:1:(nPoints-1)]*step;
            yp = xp;
            zp = [0:1:(nPlanes-1)]*zDistance;
            shift = (xp(end)-xp(1))/2;
            shiftX = app.SLM_size_px(1)/2;
            shiftY = app.SLM_size_px(2)/2;
            shiftZ = zDistance*(nPlanes-1);
            xp = xp-shift+shiftX;
            yp = yp-shift+shiftY;
            zp = zp-shiftZ/2;
%             xy = [];
            xyz = [];
            for zz = 1:length(zp)
                for xx = 1:length(xp)
                    for yy = 1:length(yp)
%                         xy = [xy; xp(xx) yp(yy)];
                        xyz = [xyz; xp(xx) yp(yy) zz];
                    end
                end
            end
%             linearInd = sub2ind(size(I), xy(:,1), xy (:,2));
%             I(linearInd) = 1;
            I(xyz(:,1),xyz(:,2),xyz(:,3))=1;
            
%             [tform, Itransformed] = applyGeometricalTransformation3D(zoomXY,1,rotation,0,mirroring,I,1);
%             Itransformed = imbinarize(Itransformed);            
%             targetPattern = Itransformed;
%             app.ITarget = targetPattern;

            [tform, Itransformed] = applyGeometricalTransformation3D_slim(zoomXY,1,rotation,0,mirroring,I,1);
            Itransformed = adaptImageToSLMSize (Itransformed,I,app.SLM_size_px, 1)
            
            targetPattern = Itransformed;
            app.ITarget = targetPattern;
            
            num_stored_patterns = size (app.Stored_Patterns,2);
            app.Stored_Patterns(num_stored_patterns+1).I_pattern = app.ITarget;
            newItem = ['Pattern ', num2str(num_stored_patterns+1)];
            app.DropDown_Stored_patterns.Items = [app.DropDown_Stored_patterns.Items newItem];
            app.DropDown_Stored_patterns.Value = newItem;
            app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Grid of points mask generated\n'])];
            scroll(app.List_messages_software_speaking, 'bottom');
            
            % Show the image in the Target Intensity window
            pbarOriginal = pbaspect(app.Axes_Target_intensity_pattern); % preserve the original PlotBoxAspectRatio
            if length(size(Itransformed)) == 2
                figure; imshow(Itransformed,[]);
                imshow(Itransformed, [], 'Parent', app.Axes_Target_intensity_pattern, 'Border', 'tight');
            else 
                figure; sliceViewer(Itransformed, 'DisplayRangeInteraction', 'On');
            end
            % Revert the properties back to their original values
            app.Axes_Target_intensity_pattern.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Target_intensity_pattern.DataAspectRatioMode = 'manual';
            app.Axes_Target_intensity_pattern.PlotBoxAspectRatio = pbarOriginal;
            
            
        end

        % Button pushed function: Button_calculate_pattern_phase
        function Button_calculate_pattern_phasePushed(app, event)
            
            idx = find(contains(app.DropDown_Stored_patterns.Items,app.DropDown_Stored_patterns.Value));
            app.ITarget = app.Stored_Patterns(idx).I_pattern;
            
            
            %Input beam amplitude
            sigma = 200;
            singleCircleImage = fspecial('gaussian', flip(app.SLM_size_px), sigma); 
            singleCircleImage=singleCircleImage/max(max(singleCircleImage)); % Normalize to 0-1. 
            initial_amplitude = singleCircleImage;
            
            %Input beam phase
            % initial_phase = (rand(size,size)*(2*pi))-pi;
            initial_phase = (rand(flip(app.SLM_size_px))*(2*pi))-pi;
            initial_field = initial_amplitude.*exp(1i*initial_phase);
            
            FT_field = fftshift(fft2(fftshift(initial_field)));
            
%             if app.verbose
%                 fontSize = 8;
%                 figure;
%                 subplot(2,3,2); 
%                 imshow(initial_amplitude, []); 
%                 title('input amplitude', 'FontSize', fontSize);
%                 axis off;
%                 subplot(2,3,3); 
%                 imshow(initial_phase, []); 
%                 title('SLM phase', 'FontSize', fontSize);
%                 axis off;
%                 subplot(2,3,5); 
%                 imshow(abs(FT_field).^2,[]);
%                 axis on;
%             end

            ee = 1;
            error = [];
            error1 = [];
            uniformity = [];
            efficiency = [];
            maschera = find(app.ITarget);
            % Iterations = 10;
            stopError = 1e-3;
            numIter = 0;
            h = waitbar(0,'Please wait...');
            maxNumIter = 200;
                
            % for ii = 1:Iterations
            while ee > stopError
                waitbar(numIter / (maxNumIter-1))
            % fprintf('Iteration: %d\n', ii) 
            
            FT_phase = angle(FT_field);
            FT_amplitude = abs(FT_field);
            Iachieved = FT_amplitude.^2;
            FT_field_updated = app.ITarget.*exp(1i*FT_phase);
            
            app.IFT_field = fftshift(ifft2(fftshift(FT_field_updated)));


            IFT_phase = angle(app.IFT_field);
            IFT_amplitude = abs(app.IFT_field);
            IFT_field_updated = initial_amplitude.*exp(1i*IFT_phase);
            
            FT_field = fftshift(fft2(fftshift(IFT_field_updated)));


            ee = sqrt( immse( Iachieved(:)./max(Iachieved(:)), app.ITarget(:)./max(app.ITarget(:)) ) );
            % Python implementation: errors' standard deviation
            ee1 = std(Iachieved(:)./max(Iachieved(:))-app.ITarget(:)./max(app.ITarget(:)));
            error = [error; ee];
            error1 = [error1; ee1];
            uniformity = [uniformity; 1-((max(Iachieved(maschera))-min(Iachieved(maschera)))/(max(Iachieved(maschera))+min(Iachieved(maschera))))];
            efficiency = [efficiency; sum(Iachieved(maschera))/sum(Iachieved(:))];
            
            numIter = numIter+1;
            if numIter > maxNumIter-1
                    break;
            end
            
%             if app.verbose
%                 subplot(2,3,5); 
%                 axis off;
%                 imshow(abs(FT_field).^2, []);
%                 title('reconstructed amplitude', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,3); 
%                 axis off;
%                 imshow(wrapTo2Pi(angle(IFT_field)), []);
%                 title('phase to be used', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,6); 
%                 axis off;
%                 imshow(wrapTo2Pi(angle(FT_field)), []);
%                 title('reconstructed phase', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,1); 
%                 imagesc(mat2rgbCplx(initial_amplitude.*exp(1i*wrapTo2Pi(angle(IFT_field))),max(initial_amplitude),1));
%                 pbaspect([max(SLM_size_px) min(SLM_size_px) 1]);
%                 axis off;
%                 title('field after SLM', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,4); 
%                 imagesc(mat2rgbCplx((abs(FT_field).^2).*exp(1i*wrapTo2Pi(angle(FT_field))),max((abs(FT_field).^2)),1));
%                 pbaspect([max(SLM_size_px) min(SLM_size_px) 1]);
%                 title('field at the sample', 'FontSize', fontSize);
%                 axis off;
%                 drawnow;
%             end
            
            
            end
            close(h)
            app.FT_field = FT_field;
            app.phase = [app.blackBand, angle(app.IFT_field), app.blackBand];
            
            idx = find(contains(app.DropDown_Stored_patterns.Items,app.DropDown_Stored_patterns.Value));
            app.Stored_Patterns(idx).phase_pattern = app.phase;
            app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Phasemap calculated for ', app.DropDown_Stored_patterns.Value,'\n'])];
            scroll(app.List_messages_software_speaking, 'bottom');
            
            
            % Show the image in the Target Intensity window
             phase_toBeSent = app.phase;
            if app.Check_include_lens_phase_Grid.Value
                phase_toBeSent = mod (phase_toBeSent+app.flatPhase_toBeSent, 2*pi)./(2*pi);
                lens_considered = 1;
            else
                phase_toBeSent = mod (phase_toBeSent, 2*pi)./(2*pi);
                lens_considered = 0;
            end
            pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
            imshow(phase_toBeSent, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Phase.DataAspectRatioMode = 'manual';
            app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
            
            disp('Phase calculated')
        end

        % Button pushed function: Button_send_pattern_phase
        function Button_send_pattern_phasePushed(app, event)

            phaseCalculated = ~isempty(app.phase);
            if phaseCalculated
                phase_toBeSent = app.phase;
            else
                phase_toBeSent = zeros(app.original_SLM_size_px)';
            end
            
            if app.Check_include_lens_phase_Grid.Value
                phase_toBeSent = mod (phase_toBeSent+app.flatPhase_toBeSent, 2*pi)./(2*pi);
                lens_considered = 1;
            else
                phase_toBeSent = mod (phase_toBeSent, 2*pi)./(2*pi);
                lens_considered = 0;
            end
            
            if app.SLM_attached
                if phaseCalculated
                    app.SLM.sendMap(phase_toBeSent);
                    if lens_considered
                        app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Phasemap of ',app.DropDown_Stored_patterns.Value,' (and lens) sent to SLM\n'])];
                        scroll(app.List_messages_software_speaking, 'bottom');
                    else
                        app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Phasemap of ',app.DropDown_Stored_patterns.Value,' (without lens) sent to SLM\n'])];
                        scroll(app.List_messages_software_speaking, 'bottom');
                    end
                end
            else
                if lens_considered
                    app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Phasemap of ',app.DropDown_Stored_patterns.Value,' (and lens) cannot be sent to SLM\n'])];
                    scroll(app.List_messages_software_speaking, 'bottom')
                else
                    app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Phasemap of ',app.DropDown_Stored_patterns.Value,' (without lens) cannot be sent to SLM\n'])];
                    scroll(app.List_messages_software_speaking, 'bottom');
                end
            end
            
            if ~phaseCalculated
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Phasemap of ',app.DropDown_Stored_patterns.Value,' was not calculated yet!\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
                
            end
            
            % Show the image in the Target Intensity window
            pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
            imshow(phase_toBeSent, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Phase.DataAspectRatioMode = 'manual';
            app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
            
        end

        % Button pushed function: Button_Upload_image
        function Button_Upload_imagePushed(app, event)
            
            select_image = 1;

            if select_image
            
                try
                %     cd(handles.SLMControlGUI_parameters.workingDirectory)
                    [filename, pathname, ~] = uigetfile({'*.tif','Image files (*.tif)';'*.*','All Files (*.*)'}, 'Pick a file'); %'MultiSelect', 'on');
                catch ME
                    errordlg(ME.message,ME.identifier)
                    return
                end
                if isnumeric(filename) && strcmp(num2str(filename),'0')
                    return
                end
                % Check whether the image is single-channel
                Iorig = imread(fullfile(pathname,filename));
                if size(Iorig,3) > 1
                    warndlg('Only single-channel images can be loaded. For two-channel images, please select the correct command from the Load menu.');
                    return
                end
                origImageSize = size(Iorig);
                if origImageSize(1) ~= origImageSize(2)
                    errordlg('Only square images can be loaded!','Please select a square image or crop it before loading');
                end
                
            else
                Iorig = imread('spine.tif');
            
            end
            
                
            if size(Iorig,1) ~= app.SLM_size_px(2) || size(Iorig,1) ~= app.SLM_size_px(2)
                I = imresize(Iorig,[min(app.SLM_size_px) min(app.SLM_size_px)],'bilinear');
            else
                I = Iorig;
            end
            
            I = imadjust(I,stretchlim(I),[0 1]);
            app.FOV = I;
            
            % Show the image in the FOV window
            pbarOriginal = pbaspect(app.Axes_Uploaded_image); % preserve the original PlotBoxAspectRatio
            imshow(app.FOV, [], 'Parent', app.Axes_Uploaded_image, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Uploaded_image.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Uploaded_image.DataAspectRatioMode = 'manual';
            app.Axes_Uploaded_image.PlotBoxAspectRatio = pbarOriginal;
            
            app.roiwindow = CROIEditor(I);
            
            % wait for roi to be assigned
            waitfor(app.roiwindow,'roi');
            if ~isvalid(app.roiwindow)
                disp('you closed the window without applying a ROI, exiting...');
            return
            end
        end

        % Button pushed function: Button_Store_manual_pattern
        function Button_Store_manual_patternPushed(app, event)
            
            zoomXY = app.Table_calibration_parameters.Data(3);
            rotation = app.Table_calibration_parameters.Data(4);
            mirroring = app.Table_calibration_parameters.Data(5);
            
            [mask, labels, n, shapes] = app.roiwindow.getROIData;

            
%             [tform, Itransformed] = applyGeometricalTransformation3D(zoomXY,1,rotation,0,mirroring,mask,1);
%             Itransformed = imfill(Itransformed, 'holes');
%             targetPattern = double(imresize(Itransformed,[min(app.SLM_size_px) min(app.SLM_size_px)]));
            
            [tform, Itransformed] = applyGeometricalTransformation3D_slim(zoomXY,1,rotation,0,mirroring,mask,0);
            Itransformed = imfill(Itransformed, 'holes');
            Itransformed = adaptImageToSLMSize (Itransformed,mask,app.SLM_size_px, 0)
            targetPattern= Itransformed;
            
            if ~isempty(app.Stored_Patterns) && isfield(app.Stored_Patterns, 'I_pattern_notTransformed') && any(cellfun(@(x) isequal(x, mask), {app.Stored_Patterns(:).I_pattern_notTransformed}'))
                
                idx = find(cellfun(@(x) isequal(x, mask), {app.Stored_Patterns(:).I_pattern_notTransformed}'))
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Custom Intensidy pattern was already generated as Pattern ', num2str(idx),'\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
                app.DropDown_Stored_patterns.Value = ['Pattern ', num2str(idx)]
                DropDown_Stored_patternsValueChanged(app, event);
                
            else
                app.ITarget = targetPattern;
                num_stored_patterns = size (app.Stored_Patterns,2);
                app.mask = mask;
                app.Stored_Patterns(num_stored_patterns+1).I_pattern = app.ITarget;
                app.Stored_Patterns(num_stored_patterns+1).I_pattern_notTransformed = mask;
                app.Stored_Patterns(num_stored_patterns+1).Shapes = shapes;
                newItem = ['Pattern ', num2str(num_stored_patterns+1)];
                app.DropDown_Stored_patterns.Items = [app.DropDown_Stored_patterns.Items newItem];
                app.DropDown_Stored_patterns.Value = newItem;
                app.List_messages_software_speaking.Items = [app.List_messages_software_speaking.Items, sprintf([datestr(datetime('now')), '  -   Custom Intensidy pattern generated for ', newItem,'\n'])];
                scroll(app.List_messages_software_speaking, 'bottom');
                
                % Show the image in the Target Intensity window
                pbarOriginal = pbaspect(app.Axes_Target_intensity_pattern); % preserve the original PlotBoxAspectRatio
                imshow(app.ITarget, [], 'Parent', app.Axes_Target_intensity_pattern, 'Border', 'tight');
                % Revert the properties back to their original values
                app.Axes_Target_intensity_pattern.PlotBoxAspectRatioMode = 'auto';
                app.Axes_Target_intensity_pattern.DataAspectRatioMode = 'manual';
                app.Axes_Target_intensity_pattern.PlotBoxAspectRatio = pbarOriginal;

            end
            
        end

        % Value changed function: DropDown_Stored_patterns
        function DropDown_Stored_patternsValueChanged(app, event)
            value = app.DropDown_Stored_patterns.Value;
            idx = find(contains(app.DropDown_Stored_patterns.Items,value));
            if isfield(app.Stored_Patterns, 'phase_pattern') && ~isempty(app.Stored_Patterns(idx).phase_pattern)
                app.phase = app.Stored_Patterns(idx).phase_pattern;
            else
                app.phase = [];
            end
            app.ITarget = app.Stored_Patterns(idx).I_pattern;
            app.mask = app.Stored_Patterns(idx).I_pattern_notTransformed;
            % Show the image in the Target Intensity window
            pbarOriginal = pbaspect(app.Axes_Target_intensity_pattern); % preserve the original PlotBoxAspectRatio
            imshow(app.ITarget, [], 'Parent', app.Axes_Target_intensity_pattern, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Target_intensity_pattern.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Target_intensity_pattern.DataAspectRatioMode = 'manual';
            app.Axes_Target_intensity_pattern.PlotBoxAspectRatio = pbarOriginal;
            
            app.Switch_FOV_panel.Value = 'Off';
            app.Switch_FOV_panel.ValueChangedFcn(app, event);
        end

        % Value changed function: Switch_FOV_panel
        function Switch_FOV_panelValueChanged(app, event)
            value = app.Switch_FOV_panel.Value;
            if strcmp(value, 'On')
                pbarOriginal = pbaspect(app.Axes_Uploaded_image); % preserve the original PlotBoxAspectRatio
                imshow(app.FOV, [], 'Parent', app.Axes_Uploaded_image, 'Border', 'tight');  
                green = cat(3, zeros(size(app.FOV)), ones(size(app.FOV)), zeros(size(app.FOV))); 
                hold(app.Axes_Uploaded_image,'on');
                h = imshow(green, 'Parent', app.Axes_Uploaded_image, 'Border', 'tight'); 
                hold(app.Axes_Uploaded_image,'off');
                set(h, 'AlphaData', app.mask.*0.5);
                % Revert the properties back to their original values
                app.Axes_Uploaded_image.PlotBoxAspectRatioMode = 'auto';
                app.Axes_Uploaded_image.DataAspectRatioMode = 'manual';
                app.Axes_Uploaded_image.PlotBoxAspectRatio = pbarOriginal;
            else
                % Show the image in the FOV window
                pbarOriginal = pbaspect(app.Axes_Uploaded_image); % preserve the original PlotBoxAspectRatio
                imshow(app.FOV, [], 'Parent', app.Axes_Uploaded_image, 'Border', 'tight');
                % Revert the properties back to their original values
                app.Axes_Uploaded_image.PlotBoxAspectRatioMode = 'auto';
                app.Axes_Uploaded_image.DataAspectRatioMode = 'manual';
                app.Axes_Uploaded_image.PlotBoxAspectRatio = pbarOriginal;
            end


        end

        % Value changed function: Switch_SLM_panel
        function Switch_SLM_panelValueChanged(app, event)
            value = app.Switch_SLM_panel.Value;
            idx = find(contains(app.DropDown_Stored_patterns.Items,value));
            phaseCalculated = ~isempty(app.phase);
            if strcmp(value, 'Achieved Intensity')
                if phaseCalculated
                    % Show the image in the Target Intensity window
                    pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
                    imshow(abs(app.FT_field).^2, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
                    % Revert the properties back to their original values
                    app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
                    app.Axes_Phase.DataAspectRatioMode = 'manual';
                    app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
                else
                    pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
                    imshow(zeros(app.SLM_size_px), 'Parent', app.Axes_Phase, 'Border', 'tight');
                    % Revert the properties back to their original values
                    app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
                    app.Axes_Phase.DataAspectRatioMode = 'manual';
                    app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
                end
            else
                if phaseCalculated
                    phase_toBeSent = app.phase;
                else
                    phase_toBeSent = zeros(app.original_SLM_size_px)';
                end
                
                if app.Check_include_lens_phase_Grid.Value
                    phase_toBeSent = mod (phase_toBeSent+app.flatPhase_toBeSent, 2*pi)./(2*pi);
                    lens_considered = 1;
                else
                    phase_toBeSent = mod (phase_toBeSent, 2*pi)./(2*pi);
                    lens_considered = 0;
                end

                % Show the image in the Target Intensity window
                pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
                imshow(phase_toBeSent, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
                % Revert the properties back to their original values
                app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
                app.Axes_Phase.DataAspectRatioMode = 'manual';
                app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
 
            end
        end

        % Value changed function: Switch_SLM_attached
        function Switch_SLM_attachedValueChanged(app, event)
            value = app.Switch_SLM_attached.Value;
            if strcmp(value, 'Y')
                app.SLM_attached = 1;
            else
                app.SLM_attached = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'MATLAB App';
            app.rectangle6 = uilabel(app.MainWindow);
            app.rectangle6.Text = '';
            app.rectangle5 = uilabel(app.MainWindow);
            app.rectangle5.Text = '';
            app.rectangle4 = uilabel(app.MainWindow);
            app.rectangle4.Text = '';
            app.rectangle3 = uilabel(app.MainWindow);
            app.rectangle3.Text = '';
            app.rectangle2 = uilabel(app.MainWindow);
            app.rectangle2.Text = '';
            app.rectangle1 = uilabel(app.MainWindow);
            app.rectangle1.Text = '';
            app.Button_SLM_connect = uibutton(app.MainWindow, 'push');
            app.Button_SLM_connect.ButtonPushedFcn = createCallbackFcn(app, @Button_SLM_connectPushed, true);
            app.Button_SLM_connect.Text = 'Connect to SLM';
            app.Label = uilabel(app.MainWindow);
            app.Label.Text = '';
            app.Lamp_SLM_connected = uilamp(app.MainWindow);
            app.LensphaseSpinnerLabel = uilabel(app.MainWindow);
            app.LensphaseSpinnerLabel.Text = 'Lens phase';
            app.Spinner_Lens_phase = uispinner(app.MainWindow);
            app.Spinner_Lens_phase.Step = 0.01;
            app.Spinner_Lens_phase.ValueChangingFcn = createCallbackFcn(app, @Spinner_Lens_phaseValueChanging, true);
            app.Spinner_Lens_phase.ValueDisplayFormat = '%.2f';
            app.Spinner_Lens_phase.Value = 6.3;
            app.Button_send_lens_phase = uibutton(app.MainWindow, 'push');
            app.Button_send_lens_phase.ButtonPushedFcn = createCallbackFcn(app, @Button_send_lens_phasePushed, true);
            app.Button_send_lens_phase.Text = 'Send Lens phase';
            app.Lamp_sent_lens_phase = uilamp(app.MainWindow);
            app.Table_calibration_parameters = uitable(app.MainWindow);
            app.Table_calibration_parameters.ColumnName = {'Points per side'; 'Points distance'; 'Zoom XY'; 'Rotation angle'; 'Mirroring'; '# Z planes'; 'Z istance'};
            app.Table_calibration_parameters.RowName = {};
            app.Table_calibration_parameters.ColumnEditable = true;
            app.Button_Generate_grid = uibutton(app.MainWindow, 'push');
            app.Button_Generate_grid.ButtonPushedFcn = createCallbackFcn(app, @Button_Generate_gridPushed, true);
            app.Button_Generate_grid.Text = {'Grid of points '; ''};
            app.Check_include_lens_phase_Grid = uicheckbox(app.MainWindow);
            app.Check_include_lens_phase_Grid.Text = 'Include lens phase';
            app.Button_send_pattern_phase = uibutton(app.MainWindow, 'push');
            app.Button_send_pattern_phase.ButtonPushedFcn = createCallbackFcn(app, @Button_send_pattern_phasePushed, true);
            app.Button_send_pattern_phase.Text = 'Send pattern phase';
            app.Button_calculate_pattern_phase = uibutton(app.MainWindow, 'push');
            app.Button_calculate_pattern_phase.ButtonPushedFcn = createCallbackFcn(app, @Button_calculate_pattern_phasePushed, true);
            app.Button_calculate_pattern_phase.Text = 'Calculate pattern phase';
            app.Text_GenerateIntensitypatternLabel = uilabel(app.MainWindow);
            app.Text_GenerateIntensitypatternLabel.Text = 'Generate Intensity pattern';
            app.Button_Upload_image = uibutton(app.MainWindow, 'push');
            app.Button_Upload_image.ButtonPushedFcn = createCallbackFcn(app, @Button_Upload_imagePushed, true);
            app.Button_Upload_image.Text = 'Upload image';
            app.Text_Calculate_the_phase = uilabel(app.MainWindow);
            app.Text_Calculate_the_phase.Text = 'Calculate the phase of the Intensity pattern';
            app.Text_Send_the_phase_to_SLM = uilabel(app.MainWindow);
            app.Text_Send_the_phase_to_SLM.Text = 'Send the phase to SLM';
            app.Button_Store_manual_pattern = uibutton(app.MainWindow, 'push');
            app.Button_Store_manual_pattern.ButtonPushedFcn = createCallbackFcn(app, @Button_Store_manual_patternPushed, true);
            app.Button_Store_manual_pattern.Text = 'Store the intensity pattern';
            app.StoredIntensityPatternsLabel = uilabel(app.MainWindow);
            app.StoredIntensityPatternsLabel.Text = 'Stored Intensity Patterns';
            app.DropDown_Stored_patterns = uidropdown(app.MainWindow);
            app.DropDown_Stored_patterns.Editable = 'on';
            app.DropDown_Stored_patterns.ValueChangedFcn = createCallbackFcn(app, @DropDown_Stored_patternsValueChanged, true);
            app.List_messages_software_speaking = uilistbox(app.MainWindow);
            app.List_messages_software_speaking.Items = {};
            app.List_messages_software_speaking.Value = {};
            app.Text_Points_grid_intensity_pattern = uilabel(app.MainWindow);
            app.Text_Points_grid_intensity_pattern.Text = 'Points Grid Intensity pattern';
            app.Text_Custom_intensity_pattern = uilabel(app.MainWindow);
            app.Text_Custom_intensity_pattern.Text = 'Custom Intensity pattern';
            app.Text_title_FOV = uilabel(app.MainWindow);
            app.Text_title_FOV.Text = 'FOV';
            app.Text_Calibration_parameters = uilabel(app.MainWindow);
            app.Text_Calibration_parameters.Text = 'Calibration parameters';
            app.Text_Log_window = uilabel(app.MainWindow);
            app.Text_Log_window.Text = 'Log window';
            app.Switch_FOV_panel = uiswitch(app.MainWindow, 'slider');
            app.Switch_FOV_panel.ValueChangedFcn = createCallbackFcn(app, @Switch_FOV_panelValueChanged, true);
            app.Switch_SLM_panel = uiswitch(app.MainWindow, 'slider');
            app.Switch_SLM_panel.Items = {'SLM phase', 'Achieved Intensity'};
            app.Switch_SLM_panel.ValueChangedFcn = createCallbackFcn(app, @Switch_SLM_panelValueChanged, true);
            app.Switch_SLM_panel.Value = 'SLM phase';
            app.SLMattachedLabel = uilabel(app.MainWindow);
            app.SLMattachedLabel.Text = 'SLM attached';
            app.Switch_SLM_attached = uiswitch(app.MainWindow, 'slider');
            app.Switch_SLM_attached.Items = {'N', 'Y'};
            app.Switch_SLM_attached.ValueChangedFcn = createCallbackFcn(app, @Switch_SLM_attachedValueChanged, true);
            app.Switch_SLM_attached.Value = 'N';
            app.Axes_Phase = uiaxes(app.MainWindow);
            app.Axes_Phase.XTick = [];
            app.Axes_Phase.YTick = [];
            app.Axes_Target_intensity_pattern = uiaxes(app.MainWindow);
            app.Axes_Target_intensity_pattern.XTick = [];
            app.Axes_Target_intensity_pattern.YTick = [];
            app.Axes_Uploaded_image = uiaxes(app.MainWindow);
            app.Axes_Uploaded_image.XTick = [];
            app.Axes_Uploaded_image.YTick = [];
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end